classdef sp_reconstruction_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImageAcquisitionPanel           matlab.ui.container.Panel
        ImageDropDownLabel              matlab.ui.control.Label
        ImageDropDown                   matlab.ui.control.DropDown
        ImageSizeLabel                  matlab.ui.control.Label
        shotsEditFieldLabel             matlab.ui.control.Label
        NumShotsEditField               matlab.ui.control.NumericEditField
        ExposuretimeSliderLabel         matlab.ui.control.Label
        ExposuretimeSlider              matlab.ui.control.Slider
        xvelocityEditFieldLabel         matlab.ui.control.Label
        xvelocityEditField              matlab.ui.control.NumericEditField
        yvelocityEditFieldLabel         matlab.ui.control.Label
        yvelocityEditField              matlab.ui.control.NumericEditField
        MotionDropDownLabel             matlab.ui.control.Label
        MotionDropDown                  matlab.ui.control.DropDown
        UIAxes_OrigImg                  matlab.ui.control.UIAxes
        UIAxes_StdCapturedImg           matlab.ui.control.UIAxes
        UIAxes_SPFirstShot              matlab.ui.control.UIAxes
        ClickButton                     matlab.ui.control.Button
        imtranslatemethodDropDown_2Label  matlab.ui.control.Label
        CaptureImtranslateMethodDropDown  matlab.ui.control.DropDown
        ImageReconstructionPanel        matlab.ui.container.Panel
        CommonParametersPanel           matlab.ui.container.Panel
        PatchSizeXEditFieldLabel        matlab.ui.control.Label
        PatchSizeXEditField             matlab.ui.control.NumericEditField
        PatchSizeYEditFieldLabel        matlab.ui.control.Label
        PatchSizeYEditField             matlab.ui.control.NumericEditField
        UIAxes_ReconstructedFinalImg    matlab.ui.control.UIAxes
        UIAxes_ReconstructedFirstShot   matlab.ui.control.UIAxes
        UIAxes_ReconstructedNoAlignment  matlab.ui.control.UIAxes
        AlignmentParametersPanel        matlab.ui.container.Panel
        PhCorrWindowLabel               matlab.ui.control.Label
        PhCorrWindowDropDown            matlab.ui.control.DropDown
        imtranslatemethodDropDownLabel  matlab.ui.control.Label
        AlignImtranslateMethodDropDown  matlab.ui.control.DropDown
        UsedenoisedshotsCheckBox        matlab.ui.control.CheckBox
        PhCorrEstimationDropDownLabel   matlab.ui.control.Label
        PhCorrEstimationDropDown        matlab.ui.control.DropDown
        CheatCheckBox                   matlab.ui.control.CheckBox
        MergeParametersPanel            matlab.ui.container.Panel
        WindowfunctionDropDownLabel     matlab.ui.control.Label
        WindowfunctionDropDown          matlab.ui.control.DropDown
        SpatialWienerfiltertuningexponentEditFieldLabel  matlab.ui.control.Label
        SpatialWienerfiltertuningexponentEditField  matlab.ui.control.NumericEditField
        UseWholeStackCheckBox           matlab.ui.control.CheckBox
        SpatialpostfilteringCheckBox    matlab.ui.control.CheckBox
        GoButton                        matlab.ui.control.Button
        StatusText                      matlab.ui.control.Label
        UIAxes_ReconstructedDirectMLE   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Acquisition
        current_image % being worked on
        current_image_path % Description
        exposure_time
        num_sp_shots
        motion_type
        linear_motion_velocity
        capture_imtranslate_method
        motion_blurred_img
        sp_shots
        
        % Align
        rc_patch_size_x
        rc_patch_size_y
        phcorr_window_type
        phcorr_estimation_method
        align_imtranslate_method
        use_denoised_shots_for_alignment
        cheat_in_alignment
        
        % Merge
        use_whole_stack
        merge_window_type
        do_spatial_wienerfilt
        spatial_wienerfilt_freq_exponent
    end
    
    methods (Access = private)
        function initialize_parameters(app)
            app.current_image_path = app.ImageDropDown.Value;
            app.num_sp_shots = app.NumShotsEditField.Value;
            app.exposure_time = app.ExposuretimeSlider.Value;
            app.motion_type = app.MotionDropDown.Value;
            app.linear_motion_velocity(1) = -app.xvelocityEditField.Value;
            app.linear_motion_velocity(2) = -app.yvelocityEditField.Value;
            app.capture_imtranslate_method = app.CaptureImtranslateMethodDropDown.Value;
            
            app.rc_patch_size_x = app.PatchSizeXEditField.Value;
            app.rc_patch_size_y = app.PatchSizeYEditField.Value;
            app.phcorr_window_type = app.PhCorrWindowDropDown.Value;
            app.phcorr_estimation_method = app.PhCorrEstimationDropDown.Value;
            app.align_imtranslate_method = app.AlignImtranslateMethodDropDown.Value;
            app.use_denoised_shots_for_alignment = app.UsedenoisedshotsCheckBox.Value;
            app.cheat_in_alignment = app.CheatCheckBox.Value;
            
            app.use_whole_stack = app.UseWholeStackCheckBox.Value;
            app.do_spatial_wienerfilt = app.SpatialpostfilteringCheckBox.Value;
            app.merge_window_type = app.WindowfunctionDropDown.Value;
            app.spatial_wienerfilt_freq_exponent = app.SpatialWienerfiltertuningexponentEditField.Value;
        end
        
        function capture_and_display_image(app)
            app.current_image = load_grayscale_img(app.current_image_path);
            app.ImageSizeLabel.Text = sprintf('(%d x %d)', size(app.current_image, 2), size(app.current_image, 1));
            
            if strcmpi(app.motion_type, 'linear')
                translations = get_linear_translations(app.linear_motion_velocity, app.num_sp_shots);
            end
            
            app.motion_blurred_img = get_motion_blurred_img(app.current_image, translations, app.capture_imtranslate_method);
            app.sp_shots = get_binary_snapshots(app.current_image, app.num_sp_shots, app.exposure_time, translations, app.capture_imtranslate_method);

            imshow(app.current_image, 'Parent', app.UIAxes_OrigImg);
            imshow(app.motion_blurred_img, 'Parent', app.UIAxes_StdCapturedImg);
            imshow(app.sp_shots(:,:,1), 'Parent', app.UIAxes_SPFirstShot);
            drawnow
        end
        
        function run_reconstruction(app)
            app.StatusText.Text = 'Working...';
            drawnow
            
            align_parameters = struct;
            align_parameters.patch_size_x = app.rc_patch_size_x;
            align_parameters.patch_size_y = app.rc_patch_size_y;
            align_parameters.window_fn = lookup_window_function(app.phcorr_window_type);
            align_parameters.phcorr_estimation_method = app.phcorr_estimation_method;
            align_parameters.imtranslate_method = app.align_imtranslate_method;
            align_parameters.true_linear_velocity = app.linear_motion_velocity;
            align_parameters.use_denoised_shots_for_alignment = app.use_denoised_shots_for_alignment;
            align_parameters.cheat = false;
            
            % Direct MLE
            app.StatusText.Text = 'Direct MLE...';
            drawnow
            mle_direct = reconstruct_flux_from_binary_snapshots_mle(app.sp_shots, app.exposure_time, 'average');
            imshow(mle_direct, 'Parent', app.UIAxes_ReconstructedDirectMLE);            
            drawnow
            
            % Setting up merging
            merge_parameters = struct;
            merge_parameters.merged_size_x = size(app.current_image, 2);
            merge_parameters.merged_size_y = size(app.current_image, 1);
            merge_parameters.window_fn = lookup_window_function(app.merge_window_type);
            merge_parameters.spatial_wienerfilt_freq_tuning_exponent = app.spatial_wienerfilt_freq_exponent;
            
            % First, not aligning/merging at all, just using the first shot.
            app.StatusText.Text = 'First Shot...';
            drawnow
            align_parameters.cheat = true; % This won't matter because we won't use the other frames anyway, but this way we don't do phase_corr.
            align_parameters.true_linear_velocity = [0 0];
            merge_parameters.use_whole_stack = false;
            merge_parameters.do_spatial_postfilt = true;
            reconstructed_oneshot = reconstruct_flux_from_binary_snapshots_mle(app.sp_shots, app.exposure_time, 'wiener', align_parameters, merge_parameters);
            imshow(reconstructed_oneshot, 'Parent', app.UIAxes_ReconstructedFirstShot);
            drawnow
            
            % Now, we merge, but do not align.
            app.StatusText.Text = 'Merge NoAlign...';
            drawnow
            align_parameters.cheat = true;
            align_parameters.true_linear_velocity = [0 0];
            merge_parameters.use_whole_stack = true;
            merge_parameters.do_spatial_postfilt = true;
            reconstructed_noalign = reconstruct_flux_from_binary_snapshots_mle(app.sp_shots, app.exposure_time, 'wiener', align_parameters, merge_parameters);
            imshow(reconstructed_noalign, 'Parent', app.UIAxes_ReconstructedNoAlignment);
            drawnow
            
            % Finally we align and merge according to the set parameters.
            app.StatusText.Text = 'Align & Merge...';
            drawnow
            align_parameters.true_linear_velocity = app.linear_motion_velocity;
            align_parameters.cheat = app.cheat_in_alignment;
            merge_parameters.use_whole_stack = app.use_whole_stack;
            merge_parameters.do_spatial_postfilt = app.do_spatial_wienerfilt;
            reconstructed_final = reconstruct_flux_from_binary_snapshots_mle(app.sp_shots, app.exposure_time, 'wiener', align_parameters, merge_parameters);
            imshow(reconstructed_final, 'Parent', app.UIAxes_ReconstructedFinalImg);
            
            app.StatusText.Text = 'Done!';
            drawnow
        end        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initialize_parameters(app);
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            app.current_image_path = app.ImageDropDown.Value;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            run_reconstruction(app);
        end

        % Button pushed function: ClickButton
        function ClickButtonPushed(app, event)
            capture_and_display_image(app);
        end

        % Value changed function: NumShotsEditField
        function NumShotsEditFieldValueChanged(app, event)
            app.num_sp_shots = app.NumShotsEditField.Value;
        end

        % Value changed function: ExposuretimeSlider
        function ExposuretimeSliderValueChanged(app, event)
            app.exposure_time = app.ExposuretimeSlider.Value;
        end

        % Value changed function: xvelocityEditField
        function xvelocityEditFieldValueChanged(app, event)
            app.linear_motion_velocity(1) = -app.xvelocityEditField.Value;
        end

        % Value changed function: yvelocityEditField
        function yvelocityEditFieldValueChanged(app, event)
            app.linear_motion_velocity(2) = -app.yvelocityEditField.Value;
        end

        % Value changed function: MotionDropDown
        function MotionDropDownValueChanged(app, event)
            app.motion_type = app.MotionDropDown.Value;
        end

        % Value changed function: PatchSizeXEditField
        function PatchSizeXEditFieldValueChanged(app, event)
            app.rc_patch_size_x = app.PatchSizeXEditField.Value;
        end

        % Value changed function: PatchSizeYEditField
        function PatchSizeYEditFieldValueChanged(app, event)
            app.rc_patch_size_y = app.PatchSizeYEditField.Value;
        end

        % Value changed function: CheatCheckBox
        function CheatCheckBoxValueChanged(app, event)
            app.cheat_in_alignment = app.CheatCheckBox.Value;
        end

        % Value changed function: PhCorrWindowDropDown
        function PhCorrWindowDropDownValueChanged(app, event)
            app.phcorr_window_type = app.PhCorrWindowDropDown.Value;
        end

        % Value changed function: AlignImtranslateMethodDropDown
        function AlignImtranslateMethodDropDownValueChanged(app, event)
            app.align_imtranslate_method = app.AlignImtranslateMethodDropDown.Value;
        end

        % Value changed function: WindowfunctionDropDown
        function WindowfunctionDropDownValueChanged(app, event)
            app.merge_window_type = app.WindowfunctionDropDown.Value;
        end

        % Value changed function: 
        % SpatialWienerfiltertuningexponentEditField
        function SpatialWienerfiltertuningexponentEditFieldValueChanged(app, event)
            app.spatial_wienerfilt_freq_exponent = app.SpatialWienerfiltertuningexponentEditField.Value;
        end

        % Value changed function: UseWholeStackCheckBox
        function UseWholeStackCheckBoxValueChanged(app, event)
            app.use_whole_stack = app.UseWholeStackCheckBox.Value;
        end

        % Value changed function: SpatialpostfilteringCheckBox
        function SpatialpostfilteringCheckBoxValueChanged(app, event)
            app.do_spatial_wienerfilt = app.SpatialpostfilteringCheckBox.Value;
        end

        % Value changed function: UsedenoisedshotsCheckBox
        function UsedenoisedshotsCheckBoxValueChanged(app, event)
            app.use_denoised_shots_for_alignment = app.UsedenoisedshotsCheckBox.Value;
        end

        % Value changed function: CaptureImtranslateMethodDropDown
        function CaptureImtranslateMethodDropDownValueChanged(app, event)
            app.capture_imtranslate_method = app.CaptureImtranslateMethodDropDown.Value;
        end

        % Value changed function: PhCorrEstimationDropDown
        function PhCorrEstimationDropDownValueChanged(app, event)
            app.phcorr_estimation_method = app.PhCorrEstimationDropDown.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageAcquisitionPanel = uipanel(app.UIFigure);
            app.ImageAcquisitionPanel.Title = 'Image Acquisition';
            app.ImageDropDownLabel = uilabel(app.ImageAcquisitionPanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.ImageAcquisitionPanel);
            app.ImageDropDown.Items = {'cameraman.tif', 'circles.png', 'peppers.png', 'bag.png', 'football.jpg', 'greens.jpg', 'mri.tif', 'fabric.png'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Value = 'cameraman.tif';
            app.ImageSizeLabel = uilabel(app.ImageAcquisitionPanel);
            app.ImageSizeLabel.Text = '(256 x 256)';
            app.shotsEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.shotsEditFieldLabel.Text = '#(shots)';
            app.NumShotsEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.NumShotsEditField.Limits = [1 Inf];
            app.NumShotsEditField.RoundFractionalValues = 'on';
            app.NumShotsEditField.ValueChangedFcn = createCallbackFcn(app, @NumShotsEditFieldValueChanged, true);
            app.NumShotsEditField.Value = 64;
            app.ExposuretimeSliderLabel = uilabel(app.ImageAcquisitionPanel);
            app.ExposuretimeSliderLabel.Text = {'Exposure'; 'time'};
            app.ExposuretimeSlider = uislider(app.ImageAcquisitionPanel);
            app.ExposuretimeSlider.Limits = [0 512];
            app.ExposuretimeSlider.ValueChangedFcn = createCallbackFcn(app, @ExposuretimeSliderValueChanged, true);
            app.ExposuretimeSlider.Value = 64;
            app.xvelocityEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.xvelocityEditFieldLabel.Text = 'x-velocity';
            app.xvelocityEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.xvelocityEditField.ValueChangedFcn = createCallbackFcn(app, @xvelocityEditFieldValueChanged, true);
            app.xvelocityEditField.Value = 0.2;
            app.yvelocityEditFieldLabel = uilabel(app.ImageAcquisitionPanel);
            app.yvelocityEditFieldLabel.Text = 'y-velocity';
            app.yvelocityEditField = uieditfield(app.ImageAcquisitionPanel, 'numeric');
            app.yvelocityEditField.ValueChangedFcn = createCallbackFcn(app, @yvelocityEditFieldValueChanged, true);
            app.yvelocityEditField.Value = 0.1;
            app.MotionDropDownLabel = uilabel(app.ImageAcquisitionPanel);
            app.MotionDropDownLabel.Text = 'Motion';
            app.MotionDropDown = uidropdown(app.ImageAcquisitionPanel);
            app.MotionDropDown.Items = {'Linear'};
            app.MotionDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionDropDownValueChanged, true);
            app.MotionDropDown.Value = 'Linear';
            app.UIAxes_OrigImg = uiaxes(app.ImageAcquisitionPanel);
            title(app.UIAxes_OrigImg, 'Original Image')
            app.UIAxes_OrigImg.XTick = [];
            app.UIAxes_OrigImg.YTick = [];
            app.UIAxes_StdCapturedImg = uiaxes(app.ImageAcquisitionPanel);
            title(app.UIAxes_StdCapturedImg, 'Captured by "normal" camera')
            app.UIAxes_StdCapturedImg.XTick = [];
            app.UIAxes_StdCapturedImg.YTick = [];
            app.UIAxes_SPFirstShot = uiaxes(app.ImageAcquisitionPanel);
            title(app.UIAxes_SPFirstShot, 'First shot of burst')
            app.UIAxes_SPFirstShot.XTick = [];
            app.UIAxes_SPFirstShot.YTick = [];
            app.ClickButton = uibutton(app.ImageAcquisitionPanel, 'push');
            app.ClickButton.ButtonPushedFcn = createCallbackFcn(app, @ClickButtonPushed, true);
            app.ClickButton.Text = 'Click!';
            app.imtranslatemethodDropDown_2Label = uilabel(app.ImageAcquisitionPanel);
            app.imtranslatemethodDropDown_2Label.Text = {'imtranslate'; 'method'};
            app.CaptureImtranslateMethodDropDown = uidropdown(app.ImageAcquisitionPanel);
            app.CaptureImtranslateMethodDropDown.Items = {'linear', 'cubic', 'fourier'};
            app.CaptureImtranslateMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CaptureImtranslateMethodDropDownValueChanged, true);
            app.CaptureImtranslateMethodDropDown.Value = 'cubic';
            app.ImageReconstructionPanel = uipanel(app.UIFigure);
            app.ImageReconstructionPanel.Title = 'Image Reconstruction';
            app.CommonParametersPanel = uipanel(app.ImageReconstructionPanel);
            app.CommonParametersPanel.Title = 'Common Parameters';
            app.PatchSizeXEditFieldLabel = uilabel(app.CommonParametersPanel);
            app.PatchSizeXEditFieldLabel.Text = 'Patch Size X';
            app.PatchSizeXEditField = uieditfield(app.CommonParametersPanel, 'numeric');
            app.PatchSizeXEditField.Limits = [1 Inf];
            app.PatchSizeXEditField.RoundFractionalValues = 'on';
            app.PatchSizeXEditField.ValueChangedFcn = createCallbackFcn(app, @PatchSizeXEditFieldValueChanged, true);
            app.PatchSizeXEditField.Value = 32;
            app.PatchSizeYEditFieldLabel = uilabel(app.CommonParametersPanel);
            app.PatchSizeYEditFieldLabel.Text = 'Patch Size Y';
            app.PatchSizeYEditField = uieditfield(app.CommonParametersPanel, 'numeric');
            app.PatchSizeYEditField.Limits = [1 Inf];
            app.PatchSizeYEditField.RoundFractionalValues = 'on';
            app.PatchSizeYEditField.ValueChangedFcn = createCallbackFcn(app, @PatchSizeYEditFieldValueChanged, true);
            app.PatchSizeYEditField.Value = 32;
            app.UIAxes_ReconstructedFinalImg = uiaxes(app.ImageReconstructionPanel);
            title(app.UIAxes_ReconstructedFinalImg, 'Final')
            app.UIAxes_ReconstructedFinalImg.XTick = [];
            app.UIAxes_ReconstructedFinalImg.YTick = [];
            app.UIAxes_ReconstructedFirstShot = uiaxes(app.ImageReconstructionPanel);
            title(app.UIAxes_ReconstructedFirstShot, 'From First Shot')
            app.UIAxes_ReconstructedFirstShot.XTick = [];
            app.UIAxes_ReconstructedFirstShot.YTick = [];
            app.UIAxes_ReconstructedNoAlignment = uiaxes(app.ImageReconstructionPanel);
            title(app.UIAxes_ReconstructedNoAlignment, 'Without Alignment')
            app.UIAxes_ReconstructedNoAlignment.XTick = [];
            app.UIAxes_ReconstructedNoAlignment.YTick = [];
            app.AlignmentParametersPanel = uipanel(app.ImageReconstructionPanel);
            app.AlignmentParametersPanel.Title = 'Alignment Parameters';
            app.PhCorrWindowLabel = uilabel(app.AlignmentParametersPanel);
            app.PhCorrWindowLabel.Text = {'PhCorr'; 'Window'};
            app.PhCorrWindowDropDown = uidropdown(app.AlignmentParametersPanel);
            app.PhCorrWindowDropDown.Items = {'box', 'cosine', 'hamming'};
            app.PhCorrWindowDropDown.ValueChangedFcn = createCallbackFcn(app, @PhCorrWindowDropDownValueChanged, true);
            app.PhCorrWindowDropDown.Value = 'cosine';
            app.imtranslatemethodDropDownLabel = uilabel(app.AlignmentParametersPanel);
            app.imtranslatemethodDropDownLabel.Text = {'imtranslate'; 'method'};
            app.AlignImtranslateMethodDropDown = uidropdown(app.AlignmentParametersPanel);
            app.AlignImtranslateMethodDropDown.Items = {'linear', 'cubic', 'fourier'};
            app.AlignImtranslateMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignImtranslateMethodDropDownValueChanged, true);
            app.AlignImtranslateMethodDropDown.Value = 'cubic';
            app.UsedenoisedshotsCheckBox = uicheckbox(app.AlignmentParametersPanel);
            app.UsedenoisedshotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsedenoisedshotsCheckBoxValueChanged, true);
            app.UsedenoisedshotsCheckBox.Text = 'Use denoised shots';
            app.PhCorrEstimationDropDownLabel = uilabel(app.AlignmentParametersPanel);
            app.PhCorrEstimationDropDownLabel.Text = {'PhCorr'; 'Estimation'};
            app.PhCorrEstimationDropDown = uidropdown(app.AlignmentParametersPanel);
            app.PhCorrEstimationDropDown.Items = {'plane_fit', 'inv_ft'};
            app.PhCorrEstimationDropDown.ValueChangedFcn = createCallbackFcn(app, @PhCorrEstimationDropDownValueChanged, true);
            app.PhCorrEstimationDropDown.Value = 'inv_ft';
            app.CheatCheckBox = uicheckbox(app.AlignmentParametersPanel);
            app.CheatCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheatCheckBoxValueChanged, true);
            app.CheatCheckBox.Text = 'Cheat';
            app.MergeParametersPanel = uipanel(app.ImageReconstructionPanel);
            app.MergeParametersPanel.Title = 'Merge Parameters';
            app.WindowfunctionDropDownLabel = uilabel(app.MergeParametersPanel);
            app.WindowfunctionDropDownLabel.Text = {'Window'; 'function'};
            app.WindowfunctionDropDown = uidropdown(app.MergeParametersPanel);
            app.WindowfunctionDropDown.Items = {'box', 'cosine', 'hamming'};
            app.WindowfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowfunctionDropDownValueChanged, true);
            app.WindowfunctionDropDown.Value = 'cosine';
            app.SpatialWienerfiltertuningexponentEditFieldLabel = uilabel(app.MergeParametersPanel);
            app.SpatialWienerfiltertuningexponentEditFieldLabel.Text = {'Spatial Wiener'; 'filter tuning'; 'exponent'};
            app.SpatialWienerfiltertuningexponentEditField = uieditfield(app.MergeParametersPanel, 'numeric');
            app.SpatialWienerfiltertuningexponentEditField.Limits = [1 16];
            app.SpatialWienerfiltertuningexponentEditField.ValueChangedFcn = createCallbackFcn(app, @SpatialWienerfiltertuningexponentEditFieldValueChanged, true);
            app.SpatialWienerfiltertuningexponentEditField.Value = 4;
            app.UseWholeStackCheckBox = uicheckbox(app.MergeParametersPanel);
            app.UseWholeStackCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseWholeStackCheckBoxValueChanged, true);
            app.UseWholeStackCheckBox.Text = 'Use Whole Stack';
            app.UseWholeStackCheckBox.Value = true;
            app.SpatialpostfilteringCheckBox = uicheckbox(app.MergeParametersPanel);
            app.SpatialpostfilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpatialpostfilteringCheckBoxValueChanged, true);
            app.SpatialpostfilteringCheckBox.Text = 'Spatial post-filtering';
            app.SpatialpostfilteringCheckBox.Value = true;
            app.GoButton = uibutton(app.ImageReconstructionPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.StatusText = uilabel(app.ImageReconstructionPanel);
            app.StatusText.Text = '';
            app.UIAxes_ReconstructedDirectMLE = uiaxes(app.ImageReconstructionPanel);
            title(app.UIAxes_ReconstructedDirectMLE, 'Direct MLE')
            app.UIAxes_ReconstructedDirectMLE.XTick = [];
            app.UIAxes_ReconstructedDirectMLE.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = sp_reconstruction_ui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end